classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TrainandTestButton          matlab.ui.control.Button
        ClassifyButton              matlab.ui.control.Button
        NeuralNetworkDropDownLabel  matlab.ui.control.Label
        NeuralNetworkDropDown       matlab.ui.control.DropDown
        DatasetDropDownLabel        matlab.ui.control.Label
        DatasetDropDown             matlab.ui.control.DropDown
    end


    methods (Access = private)

        % Button pushed function: TrainandTestButton
        function TrainandTestButtonPushed(app, event)
            dataset = '';
            neuralNetwork = '';
            
             if app.DatasetDropDown.Value == '44202'
                dataset = '44202.mat';
             elseif app.DatasetDropDown.Value == '54802'
                dataset = '54802.mat';
             end
             
             
             if app.NeuralNetworkDropDown.Value == 'recnet'
                neuralNetwork = 'recnet';
             elseif app.NeuralNetworkDropDown.Value == 'feedfo'
                neuralNetwork = 'feedfo';
             end
            
            TrainAndTest();
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
             dataset = '';
             neuralNetwork = ''; 
            
            
             if app.DatasetDropDown.Value == '44202'
                dataset = '44202.mat';
             elseif app.DatasetDropDown.Value == '54802'
                dataset = '54802.mat';
             end
             
             
             if app.NeuralNetworkDropDown.Value == 'recnet'
                neuralNetwork = 'recnet';
             elseif app.NeuralNetworkDropDown.Value == 'feedfo'
                neuralNetwork = 'feedfo';
             end
            
            
            Classify()
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TrainandTestButton = uibutton(app.UIFigure, 'push');
            app.TrainandTestButton.ButtonPushedFcn = createCallbackFcn(app, @TrainandTestButtonPushed, true);
            app.TrainandTestButton.Text = 'Train and Test';
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.NeuralNetworkDropDownLabel = uilabel(app.UIFigure);
            app.NeuralNetworkDropDownLabel.Text = 'Neural Network';
            app.NeuralNetworkDropDown = uidropdown(app.UIFigure);
            app.NeuralNetworkDropDown.Items = {'feedfo', 'recnet'};
            app.NeuralNetworkDropDown.Value = 'feedfo';
            app.DatasetDropDownLabel = uilabel(app.UIFigure);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.UIFigure);
            app.DatasetDropDown.Items = {'44202', '54802'};
            app.DatasetDropDown.Value = '44202';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Main()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end